classdef attendencesystem_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TrainButton_2                matlab.ui.control.Button
        PinEntryDoneButton           matlab.ui.control.Button
        NEWPinforRegistrationButton  matlab.ui.control.Button
        EnterPinAboveLabel           matlab.ui.control.Label
        TrainButton                  matlab.ui.control.Button
        EnterIDBelowLabel            matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        EnterIDEditField             matlab.ui.control.EditField
        DisplayLabel                 matlab.ui.control.Label
        LibraryEntryManagementSystemLabel  matlab.ui.control.Label
        NewEntryButton               matlab.ui.control.Button
        TestButton                   matlab.ui.control.Button
    end

properties (Access = private)
    id
  pindir 
  maindir
  id1
  dmin
end

    methods (Access = public)

        function dataentry(app)


            id=app.EnterIDEditField.Value;
            app.id=id;

            maindir=['D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data\' id];
            mkdir(maindir);
            app.maindir=maindir;
            iddir=['D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data\' id '\ID']; 
            mkdir(iddir);
            pindir=['D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data\' id '\PIN'];
             mkdir(pindir);
             app.pindir=pindir;
           
            %namedir=['data\' id '\NAME']; mkdir(namedir);
            % Create directories if they do not exist
    if ~exist(maindir, 'dir')
        mkdir(maindir);
    end
    if ~exist(iddir, 'dir')
        mkdir(iddir);
    end
    %if ~exist(namedir, 'dir')
       % mkdir(namedir);
   % end
 if ~exist(pindir, 'dir')
        mkdir(pindir);
    end
    % Display directory path in the Command Window
    disp(['Audio files for ID  will be saved to the directory: ', maindir]);

            fs=44100;
            recobj=audiorecorder(fs,16,1);
            text = {};
            p=1;

            idx=1;
            
          if exist('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train', 'dir') == 0
    mkdir('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train');
end

% Load ID
if isfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat')
    A = load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat');
    if isfield(A, 'ID')
        ID = A.ID; %array
    else
        ID = {};
    end
else
    ID = {};
end

%% Load NAME
%if isfile('vq_books_train\NAME.mat')
   % A = load('vq_books_train\NAME.mat');
    %if isfield(A, 'NAME')
    %    NAME = A.NAME;
    %
        %NAME = {};
    %end
%else
    %NAME = {};
%end


           text={};
            while p<=6
                text{idx,1} = 'Ready to record ID. Press OK to start recording.';
                app.TextArea.Value = text;
                idx = idx + 1;
                uiwait(msgbox('Press OK to record your ID voice sample.'));
                %recordAudiodata
                recordblocking(recobj,3);

                y=getaudiodata(recobj);
                g=project_mfcc(y); 
                g=g';
                CB1=vqsplit(g,8);

                f = sprintf('%s_%d.wav',id,p);
                DIR= fullfile(iddir,f);
              
                audiowrite(DIR,y,fs);

               %% Name
                %text{idx,1} = 'Ready to record NAME. Press OK to start recording.';
               %app.TextArea.Value = text;
              % idx = idx + 1;
              % uiwait(msgbox('Press OK to record your NAME voice sample.'));
               % Record NAME
              % recordblocking(recobj,3);

                %y=getaudiodata(recobj);
                %g=project_mfcc(y);
                %g=g';
                %CB2=vqsplit(g,8);

              % f = sprintf('%s_%d.wav', id, p);
                %DIR = fullfile(namedir, f);
                %audiowrite(DIR,y,fs);


   %Update the ID and NAME matrices
                n=size(ID,1);
                ID{n+1,1}=CB1; %ID in vq form saved in this collumn
                ID{n+1,2}=id; % id in text saved in this collumn

                %n=size(NAME,1);
                %NAME{n+1,1}=CB2;
                %NAME{n+1,2}=id;

                %Increment sample counter
                p=p+1;
            end

emptyRows = cellfun(@isempty, ID(:,2));
ID(emptyRows, :) = [];


            save('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat','ID');
             % bad de eita save('vq_books_train\NAME.mat','NAME');
             text={};
             text{1,1}='Audio entry Completed';
             text{2,1}='Input Pincode';
            
             app.TextArea.Value=text;


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)

            text={};


            recobj=audiorecorder(44100,16,1);
            fs=44100;

            text{1,1}='Speak your ID';
            app.TextArea.Value=text;


            text{2,1}='start:';
            app.TextArea.Value=text;
            
            pause(0.5)

            recordblocking(recobj,3);

            x =getaudiodata(recobj);
            sound(x,fs); 
            pause(3);
            g=project_mfcc(x); 
            g=g';


            pause(1);
%% eita bad dish, Joy
            %text{3,1}='Speak your Name';
            %app.TextArea.Value=text;


            %text{4,1}='start:';
            %app.TextArea.Value=text;
            %pause(0.5)
            %recordblocking(recobj,3);

            %y =getaudiodata(recobj);
            %sound(y,fs);
            %h=project_mfcc(y); h=h';

            
            A=load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train/ID.mat');
            ID=A.ID;
            emptyRows = cellfun(@isempty, ID(:,2));
            ID(emptyRows, :) = [];


            save('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat','ID');
             A=load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train/ID.mat');
            ID=A.ID;

           % ei ta baad de A=load('vq_books_train\NAME.mat');
           % eita bad de NAME=A.NAME;

            %%
            dmin=inf;
            for ii=1:size(ID,1)
                d=my_dist(g,ID{ii,1});
                if d<dmin
                    dmin=d;
                    id1=ID{ii,2};
                end
            end

            % Ei part thakbe na 
            % dmin=inf;
            %for ii=1:size(NAME,1)
               % d=my_dist(h,NAME{ii,1});
               % if d<dmin
                %    dmin=d;
                   % id2=NAME{ii,2};
                %end
            
          %  end


         %   load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\atsheet.mat');
            
            
app.id1=id1;
app.dmin=dmin;
%

THRESHOLD =3;
       if app.dmin < THRESHOLD 
     
          app.TextArea.Value = {['voice matched to the ID:',app.id1];'Enter your pin and click Pin Entry Done Button:'};

           else
               app.TextArea.Value ={'Access Denied'; 'Your VOICE did not match any Registered Student'};
           end


        end

        % Button pushed function: NewEntryButton
        function NewEntryButtonPushed(app, event)
            dataentry(app);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
   
    id = app.EnterIDEditField.Value;  % তুমি যে ID box এ লিখবে
    text = {};
    if isempty(id)
        text{1,1} = 'Please enter an ID first!';
        app.TextArea.Value = text;
        return;
    end

    text{1,1} = ['Training data for ID: ' id];
    app.TextArea.Value = text;

    % train folder check
    if exist('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train', 'dir') == 0
        mkdir('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train');
    end

    % Load existing data
    if isfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat')
        A = load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat'); 
        ID = A.ID;
    else
        ID = {};
    end

    if isfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\NAME.mat')
        A = load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\NAME.mat'); 
        NAME = A.NAME;
    else
        NAME = {};
    end

    % remove previous entries for this id
    ID(cellfun(@(c) strcmp(c,id), ID(:,2)), :) = [];
   % NAME(cellfun(@(c) strcmp(c,id), NAME(:,2)), :) = [];

    idPath = fullfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data\', id, '\ID');
    namePath = fullfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data\', id, '\NAME');

    new_count = 0;

    % check if folder exists
    if exist(idPath,'dir')
        %&& exist(namePath,'dir')

        idFiles = dir(fullfile(idPath, '*.wav'));
        nameFiles = dir(fullfile(namePath, '*.wav'));

        for j=1:length(idFiles)
            [y, fs] = audioread(fullfile(idPath, idFiles(j).name));
            g = project_mfcc(y); g = g';
            CB1 = vqsplit(g,8);
            n = size(ID,1);
            ID{n+1,1} = CB1; ID{n+1,2} = id;
            new_count = new_count + 1;
        end

        for j=1:length(nameFiles)
            [y, fs] = audioread(fullfile(namePath, nameFiles(j).name));
            g = project_mfcc(y); g = g';
            CB2 = vqsplit(g,8);
            n = size(NAME,1);
            NAME{n+1,1} = CB2; NAME{n+1,2} = id;
            new_count = new_count + 1;
        end

        % save updated data
        save('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\ID.mat','ID');
        save('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\vq_books_train\NAME.mat','NAME');

        text = {['Training done! Files trained: ' num2str(new_count)]};
        app.TextArea.Value = text;

    else
        text{1,1} = ['Folder not found for ID: ' id];
        app.TextArea.Value = text;
    end

        end

        % Callback function
        function TextArea_3ValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: NEWPinforRegistrationButton
        function NEWPinforRegistrationButtonPushed(app, event)
         pincode= app.EnterIDEditField.Value;

pinFile = fullfile(app.pindir, [app.id '_pin.txt']);

% Open the file for writing ('wt' = write text mode)
fid = fopen(pinFile, 'wt');

% Write the PIN to the file
if fid ~= -1
    fprintf(fid, '%s', pincode);  % assuming pincode is a string or numeric
    fclose(fid);

            text={};
            text{1,1}='Data Entry Complete';
            text{2,1}='Thank You!!';
               disp(['Audio files for ID  will be saved to the directory: ', app.maindir]);
            app.TextArea.Value=text;
            %Clear the input field
            app.EnterIDEditField.Value='';


else
    app.TextArea.Value='Failed to open PIN file for writing.';
end
        end

        % Button pushed function: PinEntryDoneButton
        function PinEntryDoneButtonPushed(app, event)
            %pin entry done for verification
 test_pin=app.EnterIDEditField.Value;
  pinfile = fullfile('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\data', app.id1, 'PIN', [app.id1 '_pin.txt']);
original_pin =(fileread(pinfile));
pinresult= strcmp(test_pin,original_pin);

THRESHOLD =3;
       if app.dmin < THRESHOLD 
           if pinresult
        
        load('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\atsheet.mat');
            t=now;
            d=datestr(t);
            idx=size(attendsheet,1);
            attendsheet{idx+1,1}=app.id1;
            attendsheet{idx+1,2}=d;
            save('D:\BUET\3-1\DSP lab\project\EEE312_DormEntryManagement-main\atsheet.mat','attendsheet');
            %display to excel
             text = {'VOICE and PIN matched';'Access Granted';['Recognized ID:',app.id1]};
             app.TextArea.Value = text;

             % Save to excel
             filename = 'Attendencebook.xlsx';
             xlswrite(filename, attendsheet);
             pause(5);
      secondapp=interphase2;
      delete(app);
           else
               app.TextArea.Value ={'Access Denied'; 'Your PIN did not match'};
           end
          
    
       else
           %Access denied
            
             text = {'Access Denied'; 'Your voice did not match any registered student.'};
        app.TextArea.Value = text;
    end


            %if strcmp(id1,id2)==1
                %disp('access granted');
               % text={};
               % text{1,1}='Access Granted';
               % text{2,1}=['From ID speech:' id1];
                % text{3,1}=['From Name Speech:' id2];
               % app.TextArea.Value=text;
           % else
                %disp('access denied');

                %text={};
                %text{1,1}='Access Denied';
                %text{2,1}=['From ID Speech:' id1];
                %text{3,1}=['From Name Speech:' id2];
               % app.TextArea.Value=text;
%end

            %filename='Attendencebook.xlsx';
    
            %xlswrite(filename,attendsheet);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.NewEntryButton = uibutton(app.UIFigure, 'push');
            app.NewEntryButton.ButtonPushedFcn = createCallbackFcn(app, @NewEntryButtonPushed, true);
            app.NewEntryButton.Text = 'New Entry';
            app.LibraryEntryManagementSystemLabel = uilabel(app.UIFigure);
            app.LibraryEntryManagementSystemLabel.Text = 'Library  Entry Management System';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.EnterIDEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.EnterIDBelowLabel = uilabel(app.UIFigure);
            app.EnterIDBelowLabel.Text = 'Enter ID Below';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.EnterPinAboveLabel = uilabel(app.UIFigure);
            app.EnterPinAboveLabel.Text = 'Enter Pin Above';
            app.NEWPinforRegistrationButton = uibutton(app.UIFigure, 'push');
            app.NEWPinforRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @NEWPinforRegistrationButtonPushed, true);
            app.NEWPinforRegistrationButton.Text = 'NEW Pin for Registration';
            app.PinEntryDoneButton = uibutton(app.UIFigure, 'push');
            app.PinEntryDoneButton.ButtonPushedFcn = createCallbackFcn(app, @PinEntryDoneButtonPushed, true);
            app.PinEntryDoneButton.Text = 'Pin Entry Done';
            app.TrainButton_2 = uibutton(app.UIFigure, 'push');
            app.TrainButton_2.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attendencesystem_modified

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end