classdef interphase2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        speakButton                    matlab.ui.control.Button
        DisplayLabel                   matlab.ui.control.Label
        EnterBookNameinspeechLabel     matlab.ui.control.Label
        LibraryBookFindingSystemLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: speakButton
        function speakButtonPushed(app, event)
            % voice_book_locator.m


% Load trained MFCC features and book labels
load('D:\BUET\3-1\DSP lab\project\book\book_mfcc_templates.mat', 'featureBank', 'bookList');

Fs = 8000;  % Sampling frequency
recDuration = 4;  % Seconds


% Start recording
recObj = audiorecorder(Fs, 16, 1, -1);

text={'Please say the name of the book:'};
app.TextArea.Value=text;

recordblocking(recObj, recDuration);

text={'Recording completed.'};
app.TextArea.Value=text;

% Retrieve audio data
audioIn = getaudiodata(recObj);

% Extract MFCC features from recorded audio
numCoeffs = 10;
overlapLen = 0.015;
windowLen = 0.020;

mfccTest = mfcc(audioIn, Fs, ...
    'LogEnergy', 'ignore', ...
    'NumCoeffs', numCoeffs, ...
    'OverlapLength', round(Fs * overlapLen), ...
    'Window', hamming(round(Fs * windowLen), 'periodic'));

mfccTest(:,1) = [];  % Remove 0th coefficient
mfccTest = mfccTest';  % Transpose to match training data format

% DTW matching against stored MFCC templates
numBooks = numel(bookList);
numSamples = 4;  % Samples per book
distances = zeros(1, numBooks);

for bookIdx = 1:numBooks
    sampleDists = zeros(1, numSamples);
    for sampleIdx = 1:numSamples
        template = featureBank{sampleIdx, bookIdx};
        try
            sampleDists(sampleIdx) = dtw(mfccTest, template');
        catch
            sampleDists(sampleIdx) = Inf;
        end
    end
    distances(bookIdx) = mean(sampleDists);
end

% Identify predicted book
[~, bestIdx] = min(distances);
predictedBook = bookList{bestIdx};

text={['Predicted Book: %s\n', predictedBook]};
app.TextArea.Value=text;

% Load Excel file and locate book position
[~, ~, excelData] = xlsread('D:\BUET\3-1\DSP lab\project\book\books.xlsx');

headerRow = excelData(1, :);
nameCol = strcmpi(headerRow, 'Name');
shelfCol = find(strcmpi(headerRow, 'Shelf'));
rowCol = find(strcmpi(headerRow, 'Row'));

foundLocation = false;
for rowIdx = 2:size(excelData,1)
    entryName = excelData{rowIdx, nameCol};
    if ischar(entryName) && strcmpi(predictedBook, entryName)
        shelf = excelData{rowIdx, shelfCol};
        row = excelData{rowIdx, rowCol};
 
 text = sprintf('Location of %s\nShelf %s\nRow %s', predictedBook, string(shelf), string(row));
        app.TextArea.Value=text;

        foundLocation = true;
        break;
    end
end

if ~foundLocation
  text={'not found'};
    app.TextArea.Value=text;
end

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LibraryBookFindingSystemLabel = uilabel(app.UIFigure);
            app.LibraryBookFindingSystemLabel.Text = 'Library Book Finding System';
            app.EnterBookNameinspeechLabel = uilabel(app.UIFigure);
            app.EnterBookNameinspeechLabel.Text = 'Enter Book Name in speech';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.speakButton = uibutton(app.UIFigure, 'push');
            app.speakButton.ButtonPushedFcn = createCallbackFcn(app, @speakButtonPushed, true);
            app.speakButton.Text = 'speak';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interphase2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end